classdef Low < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        PortfolioBackTestSectionPanel  matlab.ui.container.Panel
        BackTestOptionDropDownLabel    matlab.ui.control.Label
        BackTestOptionDropDown         matlab.ui.control.DropDown
        BackTestCanadianportfoliovolatilityButton  matlab.ui.control.StateButton
        BackTestUSportfoliovolatilityButton  matlab.ui.control.StateButton
        PortfolioVarButton             matlab.ui.control.Button
        BacktestEmergingMarketportfoliovolatilityButton  matlab.ui.control.Button
        SecenarioAnalysisPanel         matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        MacroTab                       matlab.ui.container.Tab
        GDPSliderLabel                 matlab.ui.control.Label
        GDPSlider                      matlab.ui.control.Slider
        OilPriceSliderLabel            matlab.ui.control.Label
        OilPriceSlider                 matlab.ui.control.Slider
        InflationSliderLabel           matlab.ui.control.Label
        InflationSlider                matlab.ui.control.Slider
        GenerateGraphButton            matlab.ui.control.Button
        MarketTab                      matlab.ui.container.Tab
        VIXSliderLabel                 matlab.ui.control.Label
        VIXSlider                      matlab.ui.control.Slider
        DurationSliderLabel            matlab.ui.control.Label
        DurationSlider                 matlab.ui.control.Slider
        InterestrateSliderLabel        matlab.ui.control.Label
        InterestrateSlider             matlab.ui.control.Slider
        GenerateGraphButton_4          matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        ListBox_2                      matlab.ui.control.ListBox
        GenerateTableButton            matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        RiskFactorbycategoryButton     matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        AssetAllocationgraphButtonGroup  matlab.ui.container.ButtonGroup
        ByAssetClassButton             matlab.ui.control.RadioButton
        ByBusinessSectorButton         matlab.ui.control.RadioButton
        GenerateGraphButton_2          matlab.ui.control.Button
        ByCountryButton                matlab.ui.control.RadioButton
        ByBondButton                   matlab.ui.control.RadioButton
        AllocationGraphButton          matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        SharpRatioButton               matlab.ui.control.RadioButton
        GenerateGraphButton_3          matlab.ui.control.Button
        TotalValueButton               matlab.ui.control.RadioButton
        PortfolioPerformanceButton     matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        PortfolioButton                matlab.ui.control.Button
        WealthHardButton               matlab.ui.control.Button
        BackTestResultButton           matlab.ui.control.Button
        ScenarioAnalysisButton         matlab.ui.control.Button
        FeesButton                     matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        SatisfactionfeedbackButton     matlab.ui.control.Button
        Label_4                        matlab.ui.control.Label
        DropDown_2                     matlab.ui.control.DropDown
        Label_5                        matlab.ui.control.Label
        Knob                           matlab.ui.control.Knob
    end


    properties (Access = private)
        ha % Description
    end


    methods (Access = private)

        % Value changed function: 
        % BackTestCanadianportfoliovolatilityButton
        function BackTestCanadianportfoliovolatilityButtonValueChanged(app, event)
            
            % Fill figure with axes "ha" and remove tick labels
            app.ha.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick label
            % Display image and stretch to fill axes
            if app.BackTestOptionDropDown.Value == "EWMA"
                imshow('low-risk-CA.png');
            else
                imshow('low-risk-CA2.png');
            end
           % Set limits of axes
        end

        % Value changed function: BackTestUSportfoliovolatilityButton
        function BackTestUSportfoliovolatilityButtonValueChanged(app, event)
            % Fill figure with axes "ha" and remove tick labels
            app.ha.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick label
            % Display image and stretch to fill axes
            if app.BackTestOptionDropDown.Value == "EWMA"
                imshow('low-risk-us.png');
            else
                imshow('low-risk-us2.png');
            end
           % Set limits of axes
            
        end

        % Callback function
        function ButtonPushed(app, event)
             % Fill figure with axes "ha" and remove tick labels
             app.ha.Position = [0 0 app.UIFigure.Position(3:4)];
           % Remove title, axis labels, and tick label
           % Display image and stretch to fill axes
             imshow('images.png');
           % Set limits of axes
      
        end

        % Button pushed function: PortfolioVarButton
        function PortfolioVarButtonPushed(app, event)
             % Fill figure with axes "ha" and remove tick labels
            app.ha.Position = [0 0 app.UIFigure.Position(3:4)];
            % Remove title, axis labels, and tick label
            % Display image and stretch to fill axes
            imshow('Value-At-Risk.png');
        end

        % Button pushed function: GenerateGraphButton_2
        function GenerateGraphButton_2Pushed(app, event)
            app.ha.Position = [0 0 app.UIFigure.Position(3:4)];
            if app.AssetAllocationgraphButtonGroup.Buttons=="By Asset Class"
                imshow('images.png');
            elseif app.AssetAllocationgraphButtonGroup.Buttons=="By Business Sector"
                imshow('Business.PNG');
            elseif app.AssetAllocationgraphButtonGroup.Buttons=="By Bond"
                imshow('Bond.PNG');
            elseif app.AssetAllocationgraphButtonGroup.Buttons=="By Country"
                imshow('Country.PNG');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.PortfolioBackTestSectionPanel = uipanel(app.Panel);
            app.PortfolioBackTestSectionPanel.Title = 'Portfolio Back Test Section';
            app.BackTestOptionDropDownLabel = uilabel(app.PortfolioBackTestSectionPanel);
            app.BackTestOptionDropDownLabel.Text = 'Back Test Option';
            app.BackTestOptionDropDown = uidropdown(app.PortfolioBackTestSectionPanel);
            app.BackTestOptionDropDown.Items = {'GARCH', 'EWMA'};
            app.BackTestOptionDropDown.Value = 'GARCH';
            app.BackTestCanadianportfoliovolatilityButton = uibutton(app.PortfolioBackTestSectionPanel, 'state');
            app.BackTestCanadianportfoliovolatilityButton.ValueChangedFcn = createCallbackFcn(app, @BackTestCanadianportfoliovolatilityButtonValueChanged, true);
            app.BackTestCanadianportfoliovolatilityButton.Text = 'Back Test Canadian portfolio volatility';
            app.BackTestUSportfoliovolatilityButton = uibutton(app.PortfolioBackTestSectionPanel, 'state');
            app.BackTestUSportfoliovolatilityButton.ValueChangedFcn = createCallbackFcn(app, @BackTestUSportfoliovolatilityButtonValueChanged, true);
            app.BackTestUSportfoliovolatilityButton.Text = 'Back Test US portfolio volatility';
            app.PortfolioVarButton = uibutton(app.PortfolioBackTestSectionPanel, 'push');
            app.PortfolioVarButton.ButtonPushedFcn = createCallbackFcn(app, @PortfolioVarButtonPushed, true);
            app.PortfolioVarButton.Text = 'Portfolio Var';
            app.BacktestEmergingMarketportfoliovolatilityButton = uibutton(app.PortfolioBackTestSectionPanel, 'push');
            app.BacktestEmergingMarketportfoliovolatilityButton.Text = 'Back test Emerging Market portfolio volatility';
            app.SecenarioAnalysisPanel = uipanel(app.Panel);
            app.SecenarioAnalysisPanel.Title = 'Secenario Analysis';
            app.TabGroup = uitabgroup(app.SecenarioAnalysisPanel);
            app.MacroTab = uitab(app.TabGroup);
            app.MacroTab.Title = 'Macro';
            app.GDPSliderLabel = uilabel(app.MacroTab);
            app.GDPSliderLabel.Text = 'GDP';
            app.GDPSlider = uislider(app.MacroTab);
            app.OilPriceSliderLabel = uilabel(app.MacroTab);
            app.OilPriceSliderLabel.Text = 'Oil Price';
            app.OilPriceSlider = uislider(app.MacroTab);
            app.InflationSliderLabel = uilabel(app.MacroTab);
            app.InflationSliderLabel.Text = 'Inflation';
            app.InflationSlider = uislider(app.MacroTab);
            app.GenerateGraphButton = uibutton(app.MacroTab, 'push');
            app.GenerateGraphButton.Text = 'Generate Graph';
            app.MarketTab = uitab(app.TabGroup);
            app.MarketTab.Title = 'Market';
            app.VIXSliderLabel = uilabel(app.MarketTab);
            app.VIXSliderLabel.Text = 'VIX';
            app.VIXSlider = uislider(app.MarketTab);
            app.DurationSliderLabel = uilabel(app.MarketTab);
            app.DurationSliderLabel.Text = 'Duration';
            app.DurationSlider = uislider(app.MarketTab);
            app.InterestrateSliderLabel = uilabel(app.MarketTab);
            app.InterestrateSliderLabel.Text = 'Interest rate';
            app.InterestrateSlider = uislider(app.MarketTab);
            app.GenerateGraphButton_4 = uibutton(app.MarketTab, 'push');
            app.GenerateGraphButton_4.Text = 'Generate Graph';
            app.Panel_2 = uipanel(app.Panel);
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '';
            app.ListBox_2 = uilistbox(app.Panel_2);
            app.ListBox_2.Items = {'Equity', 'Credit', 'Bond', 'FX', 'Commodity', 'Total'};
            app.ListBox_2.Value = 'Equity';
            app.GenerateTableButton = uibutton(app.Panel_2, 'push');
            app.GenerateTableButton.Text = 'Generate Table';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '';
            app.DropDown = uidropdown(app.Panel_2);
            app.DropDown.Items = {'Return', 'Covariance'};
            app.DropDown.Value = 'Return';
            app.RiskFactorbycategoryButton = uibutton(app.Panel_2, 'push');
            app.RiskFactorbycategoryButton.Text = 'Risk Factor by category';
            app.Panel_3 = uipanel(app.Panel);
            app.AssetAllocationgraphButtonGroup = uibuttongroup(app.Panel_3);
            app.AssetAllocationgraphButtonGroup.Title = 'Asset Allocation graph';
            app.ByAssetClassButton = uiradiobutton(app.AssetAllocationgraphButtonGroup);
            app.ByAssetClassButton.Text = 'By Asset Class';
            app.ByAssetClassButton.Value = true;
            app.ByBusinessSectorButton = uiradiobutton(app.AssetAllocationgraphButtonGroup);
            app.ByBusinessSectorButton.Text = 'By Business Sector';
            app.GenerateGraphButton_2 = uibutton(app.AssetAllocationgraphButtonGroup, 'push');
            app.GenerateGraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateGraphButton_2Pushed, true);
            app.GenerateGraphButton_2.Text = 'Generate Graph';
            app.ByCountryButton = uiradiobutton(app.AssetAllocationgraphButtonGroup);
            app.ByCountryButton.Text = 'By Country';
            app.ByBondButton = uiradiobutton(app.AssetAllocationgraphButtonGroup);
            app.ByBondButton.Text = 'By Bond';
            app.AllocationGraphButton = uibutton(app.Panel_3, 'push');
            app.AllocationGraphButton.Text = 'Allocation Graph';
            app.ButtonGroup = uibuttongroup(app.Panel_3);
            app.ButtonGroup.Title = 'Button Group';
            app.SharpRatioButton = uiradiobutton(app.ButtonGroup);
            app.SharpRatioButton.Text = 'Sharp Ratio';
            app.SharpRatioButton.Value = true;
            app.GenerateGraphButton_3 = uibutton(app.ButtonGroup, 'push');
            app.GenerateGraphButton_3.Text = 'Generate Graph';
            app.TotalValueButton = uiradiobutton(app.ButtonGroup);
            app.TotalValueButton.Text = 'Total Value';
            app.PortfolioPerformanceButton = uibutton(app.Panel_3, 'push');
            app.PortfolioPerformanceButton.Text = 'Portfolio Performance';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = '';
            app.ListBox = uilistbox(app.Panel_3);
            app.ListBox.Items = {'S&P500', '3M US T Bill', 'MSCI World'};
            app.ListBox.Value = 'MSCI World';
            app.PortfolioButton = uibutton(app.Panel_3, 'push');
            app.PortfolioButton.Text = 'Portfolio';
            app.WealthHardButton = uibutton(app.Panel, 'push');
            app.WealthHardButton.Text = 'Wealth Hard';
            app.BackTestResultButton = uibutton(app.Panel, 'push');
            app.BackTestResultButton.Text = 'Back Test Result';
            app.ScenarioAnalysisButton = uibutton(app.Panel, 'push');
            app.ScenarioAnalysisButton.Text = 'Scenario Analysis';
            app.FeesButton = uibutton(app.Panel, 'push');
            app.FeesButton.Text = 'Fees';
            app.Panel_4 = uipanel(app.Panel);
            app.SatisfactionfeedbackButton = uibutton(app.Panel_4, 'push');
            app.SatisfactionfeedbackButton.Text = 'Satisfaction feedback';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'Transaction', 'Management', 'Option 3', 'Option 4'};
            app.DropDown_2.Value = 'Transaction';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Knob = uiknob(app.UIFigure, 'continuous');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Low

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end